classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AmplitudeKnobLabel           matlab.ui.control.Label
        AmplitudeKnob                matlab.ui.control.Knob
        FrequencyKnobLabel           matlab.ui.control.Label
        FrequencyKnob                matlab.ui.control.Knob
        PlotButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        TypeofWaveFormDropDownLabel  matlab.ui.control.Label
        TypeofWaveFormDropDown       matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            value = app.AmplitudeKnob.Value;
            
            
        end

        % Value changed function: FrequencyKnob
        function FrequencyKnobValueChanged(app, event)
            value = app.FrequencyKnob.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
         if isequal(app.TypeofWaveFormDropDown.Value,'Sine') 
             t = 0:0.01:5*pi;
 f = app.FrequencyKnob.Value;
 a = app.AmplitudeKnob.Value;
 y = a*sin(2*pi*f*t);
      plot(app.UIAxes,t,y);  
           end
           if isequal(app.TypeofWaveFormDropDown.Value,'Square')
               t = 0:0.01:5*pi;
 f = app.FrequencyKnob.Value;
 a = app.AmplitudeKnob.Value;
 y = a*sin(2*pi*f*t);
 x=square(y)
      plot(app.UIAxes,x);
           end
      
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AmplitudeKnobLabel = uilabel(app.UIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.FrequencyKnobLabel = uilabel(app.UIFigure);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyKnobValueChanged, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TypeofWaveFormDropDownLabel = uilabel(app.UIFigure);
            app.TypeofWaveFormDropDownLabel.Text = 'Type of WaveForm';
            app.TypeofWaveFormDropDown = uidropdown(app.UIFigure);
            app.TypeofWaveFormDropDown.Items = {'Sine', 'Square'};
            app.TypeofWaveFormDropDown.Value = 'Square';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end